classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp_power                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        Lamp_redInv                     matlab.ui.control.Lamp
        Lamp_greenInv                   matlab.ui.control.Lamp
        Lamp_blueInv                    matlab.ui.control.Lamp
        Lamp_blueCap                    matlab.ui.control.Lamp
        Lamp_greenCap                   matlab.ui.control.Lamp
        Lamp_redCap                     matlab.ui.control.Lamp
        PowerButton                     matlab.ui.control.StateButton
        EmergencyStopButton             matlab.ui.control.StateButton
        BlueInkLevelGauge               matlab.ui.control.LinearGauge
        BlueInkLevelGaugeLabel          matlab.ui.control.Label
        GreenInkLevelGauge              matlab.ui.control.LinearGauge
        GreenInkLevelGaugeLabel         matlab.ui.control.Label
        RedInkLevelGauge                matlab.ui.control.LinearGauge
        RedInkLevelGaugeLabel           matlab.ui.control.Label
        BlueButton                      matlab.ui.control.Button
        BlueCartridgesinInventoryGauge  matlab.ui.control.LinearGauge
        BlueCartridgesinInventoryGaugeLabel  matlab.ui.control.Label
        GreenButton                     matlab.ui.control.Button
        GreenCartridgesinInventoryGauge  matlab.ui.control.LinearGauge
        GreenCartridgesinInventoryGaugeLabel  matlab.ui.control.Label
        RedCartridgesinInventoryGauge   matlab.ui.control.LinearGauge
        RedCartridgesinInventoryGaugeLabel  matlab.ui.control.Label
        SelectInkCartridgetoChangeLabel  matlab.ui.control.Label
        RedButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PowerButton
        function powerOn(app, event)
            value = app.PowerButton.Value;
            
            green = [0,1,0];
            red = [1,0,0];
            grey = [0.5,0.5,0.5];
            
            red_cap = 55;
            green_cap = 78;
            blue_cap = 34;
            color_matrix = [red_cap,green_cap,blue_cap];
                
            if value == 1
                app.Lamp_power.Color = green;

                app.RedCartridgesinInventoryGauge.Value = 0;
                app.GreenCartridgesinInventoryGauge.Value = 1;
                app.BlueCartridgesinInventoryGauge.Value = 0;


                %For demo purposes only - no practical use
                for i = 0:1:max(color_matrix)
                    if app.RedInkLevelGauge.Value < red_cap
                        app.RedInkLevelGauge.Value = app.RedInkLevelGauge.Value + 1;
                    end
                
                    if app.GreenInkLevelGauge.Value < green_cap
                            app.GreenInkLevelGauge.Value = app.GreenInkLevelGauge.Value + 1;
                    end
                
                    if app.BlueInkLevelGauge.Value < blue_cap
                        app.BlueInkLevelGauge.Value = app.BlueInkLevelGauge.Value + 1;
                    end
                    pause(0.03)
                end
                
                if app.RedCartridgesinInventoryGauge.Value > 0
                    app.Lamp_redInv.Color = green;
                else
                    app.Lamp_redInv.Color = red;
                end
                
                if app.RedInkLevelGauge.Value > 0
                    app.Lamp_redCap.Color = green;
                else
                    app.Lamp_redCap.Color = red;
                end
                
                if app.GreenCartridgesinInventoryGauge.Value > 0
                    app.Lamp_greenInv.Color = green;
                else
                    app.Lamp_greenInv.Color = red;
                end
                
                if app.GreenCartridgesinInventoryGauge.Value > 0
                    app.Lamp_greenCap.Color = green;
                else
                    app.Lamp_greenCap.Color = red;
                end
                
                if app.BlueCartridgesinInventoryGauge.Value > 0
                    app.Lamp_blueInv.Color = green;
                else
                    app.Lamp_blueInv.Color = red;
                end
                
                if app.BlueInkLevelGauge.Value > 0
                    app.Lamp_blueCap.Color = green;
                else
                    app.Lamp_blueCap.Color = red;
                end
                
            else
                app.Lamp_power.Color = grey;
                
                app.RedCartridgesinInventoryGauge.Value = 0;
                app.GreenCartridgesinInventoryGauge.Value = 0;
                app.BlueCartridgesinInventoryGauge.Value = 0;
                app.RedInkLevelGauge.Value = 0;
                app.GreenInkLevelGauge.Value = 0;
                app.BlueInkLevelGauge.Value = 0;
                
                app.Lamp_redInv.Color = grey;
                app.Lamp_redCap.Color = grey;
                app.Lamp_greenInv.Color = grey;
                app.Lamp_greenCap.Color = grey;
                app.Lamp_blueInv.Color = grey;
                app.Lamp_blueCap.Color = grey;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Text = 'Red';
            app.SelectInkCartridgetoChangeLabel = uilabel(app.UIFigure);
            app.SelectInkCartridgetoChangeLabel.Text = {'Select Ink Cartridge'; 'to Change'};
            app.RedCartridgesinInventoryGaugeLabel = uilabel(app.UIFigure);
            app.RedCartridgesinInventoryGaugeLabel.Text = {'Red Cartridges'; 'in Inventory'};
            app.RedCartridgesinInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.RedCartridgesinInventoryGauge.Limits = [0 10];
            app.RedCartridgesinInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GreenCartridgesinInventoryGaugeLabel = uilabel(app.UIFigure);
            app.GreenCartridgesinInventoryGaugeLabel.Text = {'Green Cartridges'; 'in Inventory'};
            app.GreenCartridgesinInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.GreenCartridgesinInventoryGauge.Limits = [0 10];
            app.GreenCartridgesinInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Text = 'Green';
            app.BlueCartridgesinInventoryGaugeLabel = uilabel(app.UIFigure);
            app.BlueCartridgesinInventoryGaugeLabel.Text = {'Blue Cartridges'; 'in Inventory'};
            app.BlueCartridgesinInventoryGauge = uigauge(app.UIFigure, 'linear');
            app.BlueCartridgesinInventoryGauge.Limits = [0 10];
            app.BlueCartridgesinInventoryGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Text = 'Blue';
            app.RedInkLevelGaugeLabel = uilabel(app.UIFigure);
            app.RedInkLevelGaugeLabel.Text = 'Red Ink Level (%)';
            app.RedInkLevelGauge = uigauge(app.UIFigure, 'linear');
            app.GreenInkLevelGaugeLabel = uilabel(app.UIFigure);
            app.GreenInkLevelGaugeLabel.Text = 'Green Ink Level (%)';
            app.GreenInkLevelGauge = uigauge(app.UIFigure, 'linear');
            app.BlueInkLevelGaugeLabel = uilabel(app.UIFigure);
            app.BlueInkLevelGaugeLabel.Text = 'Blue Ink Level (%)';
            app.BlueInkLevelGauge = uigauge(app.UIFigure, 'linear');
            app.EmergencyStopButton = uibutton(app.UIFigure, 'state');
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.PowerButton = uibutton(app.UIFigure, 'state');
            app.PowerButton.ValueChangedFcn = createCallbackFcn(app, @powerOn, true);
            app.PowerButton.Text = 'Power';
            app.Lamp_redCap = uilamp(app.UIFigure);
            app.Lamp_greenCap = uilamp(app.UIFigure);
            app.Lamp_blueCap = uilamp(app.UIFigure);
            app.Lamp_blueInv = uilamp(app.UIFigure);
            app.Lamp_greenInv = uilamp(app.UIFigure);
            app.Lamp_redInv = uilamp(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status:';
            app.Lamp_power = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end